classdef prototypeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DestinationFoldersMenu          matlab.ui.container.Menu
        RawDataMenu                     matlab.ui.container.Menu
        EditedDataMenu                  matlab.ui.container.Menu
        SelectasinglefileButton         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CroppedTab                      matlab.ui.container.Tab
        UIAxes_3                        matlab.ui.control.UIAxes
        Tab_2                           matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        Tab_3                           matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        UIAxes_5                        matlab.ui.control.UIAxes
        Tab_4                           matlab.ui.container.Tab
        UIAxes_6                        matlab.ui.control.UIAxes
        Tab_5                           matlab.ui.container.Tab
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        SaveButton                      matlab.ui.control.Button
        HSliderLabel                    matlab.ui.control.Label
        HSlider                         matlab.ui.control.Slider
        HSlider_2Label                  matlab.ui.control.Label
        HSlider_2                       matlab.ui.control.Slider
        ColorspaceDropDownLabel         matlab.ui.control.Label
        ColorspaceDropDown              matlab.ui.control.DropDown
        SSlider_2Label                  matlab.ui.control.Label
        SSlider_2                       matlab.ui.control.Slider
        SSliderLabel                    matlab.ui.control.Label
        SSlider                         matlab.ui.control.Slider
        VSlider_2Label                  matlab.ui.control.Label
        VSlider_2                       matlab.ui.control.Slider
        VSliderLabel                    matlab.ui.control.Label
        VSlider                         matlab.ui.control.Slider
        KSlider_2Label                  matlab.ui.control.Label
        KSlider_2                       matlab.ui.control.Slider
        KSliderLabel                    matlab.ui.control.Label
        KSlider                         matlab.ui.control.Slider
        BackgroundThresholdSliderLabel  matlab.ui.control.Label
        BackgroundThresholdSlider       matlab.ui.control.Slider
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        PercentageEditField_2Label      matlab.ui.control.Label
        PercentageEditField_2           matlab.ui.control.EditField
        ParseentirerawfolderButton      matlab.ui.control.Button
        AdditionalfilteringCheckBox     matlab.ui.control.CheckBox
        ApplyingcustombackgroundthresholdLabel  matlab.ui.control.Label
        ParseFolderLabel                matlab.ui.control.Label
        UseFixedThresholdCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        rawImage;
        croppedImage;
        resizedcolorspaceimg;
        resizedImage;
        tresLow = [200/255,50/255,100/255];
        tresHigh = [1,120/255,200/255];
        tresRGBLow = [0,30.6,88.4];
        tresRGBHigh = [255,154.7,134.3];
        divide = 1;
        bloodClotMask;
        bloodClotCount;
        bluePixelMask;
        rawDataPath;
        editedDataPath;
        file;
        fileLocation;
        m;
        applyingCustomBackground = false;
        singleFile = false;
    end


    methods (Access = private)
        
        
        function [] = laadKleurwaarde(app, naam)
                
            app.divide = 1;
            if strcmp(naam, 'HSV') == 1
                app.resizedcolorspaceimg = rgb2hsv(app.resizedImage);
                app.tresLow = [200/255,35/255,100/255];
                app.tresHigh = [1,1,200/255];
                app.divide = 255;
            elseif strcmp(naam, 'RGB') == 1
                app.resizedcolorspaceimg = app.resizedImage;
                app.tresLow = app.tresRGBLow;
                app.tresHigh = app.tresRGBHigh;
            elseif strcmp(naam, 'CMYK') == 1
                app.resizedcolorspaceimg = rgb2cmyk(app.resizedImage);
                app.tresLow = [0,66.3,0,0];
                app.tresHigh = [255,146.2,255,255];
                app.divide = 1;
                app.AdditionalfilteringCheckBox.Value = false;
            end           
            app.HSlider.Value = app.round(app.tresLow(1)*app.divide);
            app.EditField.Value = app.round(app.tresLow(1)*app.divide);
            app.HSlider_2.Value = app.round(app.tresHigh(1)*app.divide);
            app.EditField_2.Value = app.round(app.tresHigh(1)*app.divide);
            app.HSliderLabel.Text = naam(1);
            app.HSlider_2Label.Text = naam(1);
            
            app.SSlider.Value = app.round(app.tresLow(2)*app.divide);
            app.EditField_3.Value = app.round(app.tresLow(2)*app.divide);
            app.SSlider_2.Value = app.round(app.tresHigh(2)*app.divide);
            app.EditField_4.Value = app.round(app.tresHigh(2)*app.divide);
            app.SSliderLabel.Text = naam(2);
            app.SSlider_2Label.Text = naam(2);
            
            app.VSlider.Value = app.round(app.tresLow(3)*app.divide);
            app.EditField_5.Value = app.round(app.tresLow(3)*app.divide);
            app.VSlider_2.Value = app.round(app.tresHigh(3)*app.divide);
            app.EditField_6.Value = app.round(app.tresHigh(3)*app.divide);
            app.VSliderLabel.Text = naam(3);
            app.VSlider_2Label.Text = naam(3);
            
            v = size(app.resizedcolorspaceimg);
            %for i = 1:v(3)
            %imshow(app.resizedcolorspaceimg(:,:,1), 'Parent', app.UIAxes);
            app.Tab_3.Title = naam(1);
            %imshow(app.resizedcolorspaceimg(:,:,2), 'Parent', app.UIAxes_5);
            app.Tab.Title = naam(2);
            %imshow(app.resizedcolorspaceimg(:,:,3), 'Parent', app.UIAxes_6);
            app.Tab_4.Title = naam(3);
            %end
            if v(3) > 3
                app.KSlider.Value = app.round(app.tresLow(4)*app.divide);
                app.EditField_7.Value = app.round(app.tresLow(4)*app.divide);
                app.KSlider_2.Value = app.round(app.tresHigh(4)*app.divide);
                app.EditField_8.Value = app.round(app.tresHigh(4)*app.divide);
                app.KSliderLabel.Text = naam(4);
                app.KSlider_2Label.Text = naam(4);
                app.KSlider.Visible = true;
                app.EditField_7.Visible = true;
                app.KSlider_2.Visible = true;
                app.EditField_8.Visible = true;
                
                %imshow(app.resizedcolorspaceimg(:,:,4), 'Parent', app.UIAxes_7);
                app.Tab_5.Title = naam(4);
                
                [modifiedIm, mask] = bewerkAfbeeeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide,app.KSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide, app.KSlider_2.Value/app.divide], app.AdditionalfilteringCheckBox.Value);
                imshow(app.showMask(4, mask), 'Parent', app.UIAxes_7);
            else
                app.KSlider.Visible = false;
                app.EditField_7.Visible = false;
                app.KSlider_2.Visible = false;
                app.EditField_8.Visible = false;
                app.KSliderLabel.Text = '';
                app.KSlider_2Label.Text = '';
                
                app.Tab_5.Title = '';
                
                [modifiedIm, mask] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide], app.AdditionalfilteringCheckBox.Value);           
            end
                        
            app.PercentageEditField_2.Value = strcat(num2str(min(100*nnz(mask)/(app.bloodClotCount), 100)), "%");

            
            % inladen bewerkte foto
            app.bluePixelMask = mask;
            imshow(app.showMask(1, mask), 'Parent', app.UIAxes);
            imshow(app.showMask(2, mask), 'Parent', app.UIAxes_5);
            imshow(app.showMask(3, mask), 'Parent', app.UIAxes_6);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
        end
        
        function rounded = round(app, value)
            rounded = double(min(255, max(0, value)));
        end
        
        function [im] = showMask(app, index, mask)
            c = app.resizedcolorspaceimg(:,:,index);
            
            %im = cMask;
            applied = c;
            applied(mask) = applied(mask) * 2;
            applied(applied > 255) = 255;
            c(mask) = c(mask) * 0.5;
            
            im = cat(3, c, c, applied);
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectasinglefileButton
        function SelectasinglefileButtonPushed(app, event)
        [app.file, app.fileLocation]=uigetfile({'*.*',  'All Files (*.*)'}, 'Select file', app.rawDataPath);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if app.file ~= 0
                
                app.singleFile = true;
                
                progressBar = waitbar(0, 'Loading your blood clot');
                
                %MASK BEPALEN
                app.rawImage = imread(fullfile(app.fileLocation, app.file));
                app.rawImage(end-80:end,1:330,:) = 255;

                gray = rgb2gray(app.rawImage);
                bestThreshold = getBestThreshold(gray, 200);
                mask = gray > bestThreshold;
                gray(mask) = 0;
                gray(~mask) = 255;
                se = strel('disk',1);
                fillHoles = imclose(gray,se);
                fillHoles = ~bwareaopen(~fillHoles, 1500);
                finalMask = bwareaopen(fillHoles, 5000);
                app.bloodClotMask = finalMask;

                waitbar(0.25, progressBar, 'Loading your blood clot')
                
                %MASK TOEPASSEN
                app.croppedImage = app.rawImage;
                r = app.croppedImage(:,:,1);
                g = app.croppedImage(:,:,2);
                b = app.croppedImage(:,:,3);
                r(finalMask==0) = 255;
                g(finalMask==0) = 255;
                b(finalMask==0) = 255;
                app.croppedImage(:,:,1) = r;
                app.croppedImage(:,:,2) = g;
                app.croppedImage(:,:,3) = b;

                waitbar(0.66, progressBar, 'Loading your blood clot')
                
                %AFBEELDING BIJSNIJDEN
                [height, width] = size(finalMask);
                buffer = 300;
         
                [rows, columns] = find(finalMask);
                row1 = max([min(rows) - buffer, 1]);
                row2 = min([max(rows) + buffer, height]);
                col1 = max([min(columns) - buffer, 1]);
                col2 = min([max(columns) + buffer, width]);
                              
                app.rawImage = app.rawImage(row1:row2, col1:col2,:);
                app.croppedImage = app.croppedImage(row1:row2, col1:col2,:);
                
                %AFBEELDINGEN LADEN
                imshow(app.croppedImage, 'Parent', app.UIAxes_3);
                imshow(imresize(app.rawImage, 0.25), 'Parent', app.UIAxes_4)
                
                app.resizedImage = imresize(app.croppedImage,0.3);
                app.resizedcolorspaceimg = rgb2hsv(app.resizedImage);
                
                waitbar(1, progressBar, 'Loading your blood clot')
                close(progressBar)
                            
                %BIJGESNEDEN FOTO OPSLAAN
                %imwrite(app.croppedImage, strcat(app.editedDataPath, app.file, 'CLEAN.jpg'))
                imwrite(app.croppedImage, fullfile(app.editedDataPath, strcat(app.file, '_CLEAN.tif')),'Compression','none','Resolution',100);
                
                %WAARDEN OVERSCHRIJVEN NAAR SLIDERS
                %app.HSlider.Value = app.tresLow(1)*255;
                %app.HSlider_2.Value = app.tresHigh(1)*255;
                %app.SSlider.Value = app.tresLow(2)*255;
                %app.SSlider_2.Value = app.tresHigh(2)*255;
                %app.VSlider.Value = app.tresLow(3)*255;
                %app.VSlider_2.Value = app.tresHigh(3)*255;
                app.BackgroundThresholdSlider.Value = bestThreshold;
                
                app.m = min(2000/max(size(app.croppedImage)), 1); % niet vergrote afbeelding
                app.resizedImage = imresize(app.croppedImage,app.m);
                
                resizedMask = imresize(app.bloodClotMask,app.m);
                app.bloodClotCount = nnz(resizedMask);
                
                % inladen bewerkte foto
                app.laadKleurwaarde(app.ColorspaceDropDown.Value);            
             end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            imwrite(app.croppedImage, fullfile(app.editedDataPath, strcat(app.file, '_CLEAN.tif')),'Compression','none','Resolution',100);
                %imwrite(app.croppedImage, strcat(app.editedDataPath, app.file, 'CLEAN.jpg'))
        end

        % Value changed function: AdditionalfilteringCheckBox, 
        % HSlider, HSlider_2, SSlider, SSlider_2, VSlider, VSlider_2
        function HSliderValueChanged(app, event)

            %figure
            %imshow(hsvimg);
            v = size(app.resizedcolorspaceimg);
            
            %[v1, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, );
            %[v2, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.SSlider.Value/app.divide], [app.SSlider_2.Value/app.divide]);
            %imshow(v2, 'Parent', app.UIAxes_5);
            %[v3, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.VSlider.Value/app.divide], [app.VSlider_2.Value/app.divide]);
            %imshow(v3, 'Parent', app.UIAxes_6);
            if v(3) > 3
                %[v4, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.KSlider.Value/app.divide], [app.KSlider_2.Value/app.divide]);
                %imshow(v4, 'Parent', app.UIAxes_7);
                [modifiedIm, mask] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide, app.KSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide, app.KSlider_2.Value/app.divide], app.AdditionalfilteringCheckBox.Value);
                imshow(app.showMask(4, mask), 'Parent', app.UIAxes_7);
            else
                [modifiedIm, mask] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide], app.AdditionalfilteringCheckBox.Value);
            end
            %[modifiedIm, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/divide,app.SSlider.Value/divide,app.VSlider.Value/divide], [app.HSlider_2.Value/divide,app.SSlider_2.Value/divide,app.VSlider_2.Value/divide]);
            app.PercentageEditField_2.Value = strcat(num2str(min(100*nnz(mask)/(app.bloodClotCount), 100)), "%");
            app.bluePixelMask = mask;
            imshow(app.showMask(1, mask), 'Parent', app.UIAxes);
            imshow(app.showMask(2, mask), 'Parent', app.UIAxes_5);
            imshow(app.showMask(3, mask), 'Parent', app.UIAxes_6);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
            
            
            %[modifiedIm, mask] = applyThreshold(hsvimg, [200/255,35/255,0/255], [1,1,200/255]); % purple mask
            %[modifiedIm, mask] = applyThreshold(app.resizedhsvimg, [value/255,50/255,100/255], [1,120/255,200/255]); % black mask
            %[modifiedIm2, mask2] = applyThreshold(Im, [0,0,0], [60,60,60]); % black mask
            
            %imwrite(applyMask(Im, mask, 1.5),'test.tif','Compression','none','Resolution',100);
            %imwrite(hsvimg,'hsvimg.tif','Compression','none','Resolution',100);
            %mask = mask1 | mask2;
            %mask = bwareaopen(mask, 10); % ruis verwijderen
            %figure
            %imshow(mask);
            %se = strel('disk',1);
            %mask = imclose(mask,se);
            %figure
            %imshow(mask);
            
            %newIm = applyMask(app.resizedhsvimg, mask, 1.5);


            %figure
            %imshow(newIm);
            %imshow(hsv2rgb(modifiedIm));
            %imwrite(newIm,'test.tif','Compression','none','Resolution',100);
            %% Filteren mask
            %newMask = filtermask(app.resizedhsvimg, mask);
            %newMask = mask;
            %newMask = bwareaopen(newMask, 10); % ruis verwijderen
            %newMask = mask;
            %imshow(newMask);
            %se = strel('disk',1);
            
            %se = strel('diamond',1);
            %newMask = imclose(newMask,se);
            
            %imshow(applyMask(app.resizedImage, newMask, 2), 'Parent', app.UIAxes_2);
            %imwrite(applyMask(Im, newMask, 2),'test2.tif','Compression','none','Resolution',100);
        end

        % Value changed function: ColorspaceDropDown
        function ColorspaceDropDownValueChanged(app, event)
            value = app.ColorspaceDropDown.Value;
            app.laadKleurwaarde(value)
        end

        % Menu selected function: RawDataMenu
        function RawDataMenuSelected(app, event)
            app.rawDataPath = uigetdir%, '\');
            disp(app.rawDataPath);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Menu selected function: EditedDataMenu
        function EditedDataMenuSelected(app, event)
            app.editedDataPath = uigetdir%, '\');
            disp(app.editedDataPath);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: BackgroundThresholdSlider
        function BackgroundThresholdSliderValueChanged(app, event)
            value = app.BackgroundThresholdSlider.Value;
            
            if app.applyingCustomBackground == false && app.singleFile
            app.applyingCustomBackground = true;
            %disp('updating background')
            app.ApplyingcustombackgroundthresholdLabel.Visible = 1;
            app.BackgroundThresholdSlider.Enable = false;
            drawnow
            
            
            %MASK BEPALEN
            gray = rgb2gray(app.rawImage);
            mask = gray > value;
            gray(mask) = 0;
            gray(~mask) = 255;
            se = strel('disk',1);
            fillHoles = imclose(gray,se);
            fillHoles = ~bwareaopen(~fillHoles, 1500);
            finalMask = bwareaopen(fillHoles, 5000);
            
            app.bloodClotMask = finalMask;
            
            %MASK TOEPASSEN
            app.croppedImage = app.rawImage;
            r = app.croppedImage(:,:,1);
            g = app.croppedImage(:,:,2);
            b = app.croppedImage(:,:,3);
            r(finalMask==0) = 255;
            g(finalMask==0) = 255;
            b(finalMask==0) = 255;
            app.croppedImage(:,:,1) = r;
            app.croppedImage(:,:,2) = g;
            app.croppedImage(:,:,3) = b;
            app.croppedImage(end-80:end,1:330,:) = 255;
                       
            %AFBEELDINGEN LADEN
            imshow(app.croppedImage, 'Parent', app.UIAxes_3);
            
            app.m = min(2000/max(size(app.croppedImage)), 1); % niet vergrote afbeelding
            app.resizedImage = imresize(app.croppedImage,app.m);
            
            resizedMask = imresize(app.bloodClotMask,app.m);
            app.bloodClotCount = nnz(resizedMask);
            
            % inladen bewerkte foto
            app.laadKleurwaarde(app.ColorspaceDropDown.Value);  
            
            %disp('updating background finished')
            app.ApplyingcustombackgroundthresholdLabel.Visible = 0;
            app.applyingCustomBackground = false;
            app.BackgroundThresholdSlider.Value = value;
            app.BackgroundThresholdSlider.Enable = true;
            end
        end

        % Value changing function: HSlider
        function HSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue;
        end

        % Value changing function: HSlider_2
        function HSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value = changingValue;
        end

        % Value changing function: SSlider
        function SSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value = changingValue;
        end

        % Value changing function: SSlider_2
        function SSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.Value = changingValue;
        end

        % Value changing function: VSlider
        function VSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value = changingValue;
        end

        % Value changing function: VSlider_2
        function VSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_6.Value = changingValue;
        end

        % Value changing function: KSlider
        function KSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_7.Value = changingValue;
        end

        % Value changing function: KSlider_2
        function KSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_8.Value = changingValue;
        end

        % Button pushed function: ParseentirerawfolderButton
        function ParseentirerawfolderButtonPushed(app, event)
            %app.UIFigure.Visible = 'off';
            %app.UIFigure.Visible = 'on';
            
            app.singleFile = false;
            
            app.ParseFolderLabel.Text = "";
            app.ParseFolderLabel.Visible = true;
            app.ParseentirerawfolderButton.Enable = false;
            
            images = dir(fullfile(app.rawDataPath, '*.tif')); 
            %images = [];
            %for k = 1 : length(file)
            %    images = [images string(file(k).name)];
            %end
            disp(images);
            %images = string(resultaat);
            if ~isempty(images)
                for i = 1:length(images)
                    %disp(strcat(app.rawDataPath, images(i)))
                    app.ParseFolderLabel.Text = strcat("Parsing image [", num2str(i), '/', num2str(length(images)), ']');
                    drawnow
                    path = fullfile(images(i).folder, images(i).name);
                    %disp(path);
                    app.rawImage = imread(path);
                    app.rawImage(end-80:end,1:330,:) = 255;

                    gray = rgb2gray(app.rawImage);
                    if app.UseFixedThresholdCheckBox.Value == true 
                        threshold = app.BackgroundThresholdSlider.Value;
                    else
                        threshold = getBestThreshold(gray, 200);
                    end
                    
                    mask = gray > threshold;
                    gray(mask) = 0;
                    gray(~mask) = 255;
                    se = strel('disk',1);
                    fillHoles = imclose(gray,se);
                    fillHoles = ~bwareaopen(~fillHoles, 1500);
                    finalMask = bwareaopen(fillHoles, 5000);
                    %app.bloodClotMask = finalMask;                               
                    
                    app.croppedImage = app.rawImage;
                    r = app.croppedImage(:,:,1);
                    g = app.croppedImage(:,:,2);
                    b = app.croppedImage(:,:,3);
                    r(finalMask==0) = 255;
                    g(finalMask==0) = 255;
                    b(finalMask==0) = 255;
                    app.croppedImage(:,:,1) = r;
                    app.croppedImage(:,:,2) = g;
                    app.croppedImage(:,:,3) = b;

                    
                    [height, width] = size(finalMask);
                    buffer = 300;
             
                    [rows, columns] = find(finalMask);
                    row1 = max([min(rows) - buffer, 1]);
                    row2 = min([max(rows) + buffer, height]);
                    col1 = max([min(columns) - buffer, 1]);
                    col2 = min([max(columns) + buffer, width]);
                                       
                    app.rawImage = app.rawImage(row1:row2, col1:col2,:);
                    app.croppedImage = app.croppedImage(row1:row2, col1:col2,:);
                    %app.croppedImage(end-80:end,1:330,:) = 255;
                 
                    
                    %imwrite(app.croppedImage, strcat(app.editedDataPath, images(1), 'CLEAN.jpg'))
                    imwrite(app.croppedImage, fullfile(images(i).folder, strcat(images(i).name, '_CLEAN.tif')),'Compression','none','Resolution',100);
                end
                app.ParseFolderLabel.Visible = false;
                app.ParseentirerawfolderButton.Enable = true;
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.HSlider.Value = app.round(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.HSlider_2.Value = app.round(value);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.SSlider.Value = app.round(value);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.SSlider_2.Value = app.round(value);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.VSlider.Value = app.round(value);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.VSlider_2.Value = app.round(value);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            app.KSlider.Value = app.round(value);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.KSlider_2.Value = app.round(value);
        end

        % Value changed function: UseFixedThresholdCheckBox
        function UseFixedThresholdCheckBoxValueChanged(app, event)
            value = app.UseFixedThresholdCheckBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DestinationFoldersMenu = uimenu(app.UIFigure);
            app.DestinationFoldersMenu.Text = 'Destination Folders';
            app.RawDataMenu = uimenu(app.DestinationFoldersMenu);
            app.RawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDataMenuSelected, true);
            app.RawDataMenu.Text = 'Raw Data';
            app.EditedDataMenu = uimenu(app.DestinationFoldersMenu);
            app.EditedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @EditedDataMenuSelected, true);
            app.EditedDataMenu.Text = 'Edited Data';
            app.SelectasinglefileButton = uibutton(app.UIFigure, 'push');
            app.SelectasinglefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectasinglefileButtonPushed, true);
            app.SelectasinglefileButton.Text = 'Select a single file';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CroppedTab = uitab(app.TabGroup);
            app.CroppedTab.Title = 'Cropped';
            app.UIAxes_3 = uiaxes(app.CroppedTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Tab';
            app.UIAxes_2 = uiaxes(app.Tab_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab_3);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_5 = uiaxes(app.Tab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Tab';
            app.UIAxes_6 = uiaxes(app.Tab_4);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = 'Tab';
            app.UIAxes_7 = uiaxes(app.Tab_5);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HSliderLabel = uilabel(app.UIFigure);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.UIFigure);
            app.HSlider.Limits = [0 255];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider.ValueChangingFcn = createCallbackFcn(app, @HSliderValueChanging, true);
            app.HSlider_2Label = uilabel(app.UIFigure);
            app.HSlider_2Label.Text = 'H';
            app.HSlider_2 = uislider(app.UIFigure);
            app.HSlider_2.Limits = [0 255];
            app.HSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider_2.ValueChangingFcn = createCallbackFcn(app, @HSlider_2ValueChanging, true);
            app.ColorspaceDropDownLabel = uilabel(app.UIFigure);
            app.ColorspaceDropDownLabel.Text = 'Color space';
            app.ColorspaceDropDown = uidropdown(app.UIFigure);
            app.ColorspaceDropDown.Items = {'RGB', 'HSV', 'CMYK', ''};
            app.ColorspaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorspaceDropDownValueChanged, true);
            app.ColorspaceDropDown.Value = 'HSV';
            app.SSlider_2Label = uilabel(app.UIFigure);
            app.SSlider_2Label.Text = 'S';
            app.SSlider_2 = uislider(app.UIFigure);
            app.SSlider_2.Limits = [0 255];
            app.SSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.SSlider_2.ValueChangingFcn = createCallbackFcn(app, @SSlider_2ValueChanging, true);
            app.SSliderLabel = uilabel(app.UIFigure);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.UIFigure);
            app.SSlider.Limits = [0 255];
            app.SSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.SSlider.ValueChangingFcn = createCallbackFcn(app, @SSliderValueChanging, true);
            app.VSlider_2Label = uilabel(app.UIFigure);
            app.VSlider_2Label.Text = 'V';
            app.VSlider_2 = uislider(app.UIFigure);
            app.VSlider_2.Limits = [0 255];
            app.VSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSlider_2.ValueChangingFcn = createCallbackFcn(app, @VSlider_2ValueChanging, true);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [0 255];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSlider.ValueChangingFcn = createCallbackFcn(app, @VSliderValueChanging, true);
            app.KSlider_2Label = uilabel(app.UIFigure);
            app.KSlider_2Label.Text = 'K';
            app.KSlider_2 = uislider(app.UIFigure);
            app.KSlider_2.Limits = [0 255];
            app.KSlider_2.ValueChangingFcn = createCallbackFcn(app, @KSlider_2ValueChanging, true);
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [0 255];
            app.KSlider.ValueChangingFcn = createCallbackFcn(app, @KSliderValueChanging, true);
            app.BackgroundThresholdSliderLabel = uilabel(app.UIFigure);
            app.BackgroundThresholdSliderLabel.Text = 'Background Threshold';
            app.BackgroundThresholdSlider = uislider(app.UIFigure);
            app.BackgroundThresholdSlider.Limits = [180 255];
            app.BackgroundThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BackgroundThresholdSliderValueChanged, true);
            app.BackgroundThresholdSlider.Value = 190;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.PercentageEditField_2Label = uilabel(app.UIFigure);
            app.PercentageEditField_2Label.Text = 'Percentage';
            app.PercentageEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ParseentirerawfolderButton = uibutton(app.UIFigure, 'push');
            app.ParseentirerawfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ParseentirerawfolderButtonPushed, true);
            app.ParseentirerawfolderButton.Text = 'Parse entire raw folder';
            app.AdditionalfilteringCheckBox = uicheckbox(app.UIFigure);
            app.AdditionalfilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.AdditionalfilteringCheckBox.Text = 'Additional filtering';
            app.AdditionalfilteringCheckBox.Value = true;
            app.ApplyingcustombackgroundthresholdLabel = uilabel(app.UIFigure);
            app.ApplyingcustombackgroundthresholdLabel.Visible = 'off';
            app.ApplyingcustombackgroundthresholdLabel.Text = 'Applying custom background threshold ...';
            app.ParseFolderLabel = uilabel(app.UIFigure);
            app.ParseFolderLabel.Visible = 'off';
            app.ParseFolderLabel.Text = 'ParseFolderLabel';
            app.UseFixedThresholdCheckBox = uicheckbox(app.UIFigure);
            app.UseFixedThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFixedThresholdCheckBoxValueChanged, true);
            app.UseFixedThresholdCheckBox.Text = 'Use Fixed Threshold';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototypeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end