classdef prototypeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FotokiezenButton               matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        CroppedTab                     matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        PaarsTab                       matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        GeelTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        PercentageGeelEditFieldLabel   matlab.ui.control.Label
        PercentageGeelEditField        matlab.ui.control.NumericEditField
        PercentagePaarsEditFieldLabel  matlab.ui.control.Label
        PercentagePaarsEditField       matlab.ui.control.NumericEditField
        OpslaanalsDropDownLabel        matlab.ui.control.Label
        OpslaanalsDropDown             matlab.ui.control.DropDown
        OpslaanButton                  matlab.ui.control.Button
        HSliderLabel                   matlab.ui.control.Label
        HSlider                        matlab.ui.control.Slider
        HSlider_2Label                 matlab.ui.control.Label
        HSlider_2                      matlab.ui.control.Slider
        ColorspaceDropDownLabel        matlab.ui.control.Label
        ColorspaceDropDown             matlab.ui.control.DropDown
        SSlider_2Label                 matlab.ui.control.Label
        SSlider_2                      matlab.ui.control.Slider
        SSliderLabel                   matlab.ui.control.Label
        SSlider                        matlab.ui.control.Slider
        VSlider_2Label                 matlab.ui.control.Label
        VSlider_2                      matlab.ui.control.Slider
        VSliderLabel                   matlab.ui.control.Label
        VSlider                        matlab.ui.control.Slider
        LowerEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.EditField
        HigherEditFieldLabel           matlab.ui.control.Label
        HigherEditField                matlab.ui.control.EditField
        MapkiezenButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        croppedImage
        resizedcolorspaceimg
        resizedImage
        tresLow = [200/255,50/255,100/255,0]
        tresHigh = [1,120/255,200/255,0]
    end


    methods (Access = private)

        % Button pushed function: FotokiezenButton
        function FotokiezenButtonPushed(app, event)
            [file,path]=uigetfile({'*.*',  'All Files (*.*)'}, 'Select file');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if file ~= 0
                progressBar = waitbar(0, 'Loading your blood clot');
                Im = imread(file);
                imshow(imresize(Im, 0.25), 'Parent', app.UIAxes_4)
                %figure
                gray = rgb2gray(Im);
                %imshow(gray)
                mask = gray > 200;
                gray(mask) = 0;
                gray(~mask) = 255;
                %imshow(gray)
                fillHoles = imfill(gray,'holes');
                %imshow(fillHoles)
                %figure
                beter = bwareaopen(fillHoles, 1500);
                %imshow(beter)
                %figure
                
                waitbar(0.25, progressBar, 'Loading your blood clot')
                
                r = Im(:,:,1);
                g = Im(:,:,2);
                b = Im(:,:,3);
                r(beter==0) = 255;
                g(beter==0) = 255;
                b(beter==0) = 255;
                Im(:,:,1) = r;
                Im(:,:,2) = g;
                Im(:,:,3) = b;
                
                %imshow(Im)
                %figure
                
                [height, width] = size(beter);
                buffer = 300;
                
                waitbar(0.66, progressBar, 'Loading your blood clot')
                
                [rows, columns] = find(beter);
                row1 = max([min(rows) - buffer, 1]);
                row2 = min([max(rows) + buffer, height]);
                col1 = max([min(columns) - buffer, 1]);
                col2 = min([max(columns) + buffer, width]);
                
                waitbar(1, progressBar, 'Loading your blood clot')
                close(progressBar)
                
                app.croppedImage = Im(row1:row2, col1:col2,:);
                imshow(app.croppedImage, 'Parent', app.UIAxes_3);
                
                app.resizedImage = imresize(app.croppedImage,0.3);
                app.resizedcolorspaceimg = rgb2hsv(app.resizedImage);
                
                app.HSlider.Value = app.tresLow(1)*255;
                app.HSlider_2.Value = app.tresHigh(1)*255;
                app.SSlider.Value = app.tresLow(2)*255;
                app.SSlider_2.Value = app.tresHigh(2)*255;
                app.VSlider.Value = app.tresLow(3)*255;
                app.VSlider_2.Value = app.tresHigh(3)*255;
                
                % inladen bewerkte foto
                divide = 1;
                if app.ColorspaceDropDown.Value == 'HSV'
                    divide = 255;
                end
                [modifiedIm, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/divide,app.SSlider.Value/divide,app.VSlider.Value/divide], [app.HSlider_2.Value/divide,app.SSlider_2.Value/divide,app.VSlider_2.Value/divide]);
                imshow(modifiedIm, 'Parent', app.UIAxes_2);
                
             end
        end

        % Button pushed function: OpslaanButton
        function OpslaanButtonPushed(app, event)

            if app.OpslaanalsDropDown.Value == 'Hoge resolutie'
                imwrite(app.croppedImage, 'testOpslaanButtonHoog.jpg', 'Quality', 100)
            else
                imwrite(app.croppedImage, 'testOpslaanButtonLaag.jpg', 'Quality', 5)
            end
        end

        % Value changed function: HSlider, HSlider_2, SSlider, 
        % SSlider_2, VSlider, VSlider_2
        function HSliderValueChanged(app, event)

            %figure
            %imshow(hsvimg);
            divide = 1;
            if app.ColorspaceDropDown.Value == 'HSV'
                divide = 255;
            end
            [modifiedIm, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/divide,app.SSlider.Value/divide,app.VSlider.Value/divide], [app.HSlider_2.Value/divide,app.SSlider_2.Value/divide,app.VSlider_2.Value/divide]);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
            
            app.LowerEditField.Value = strcat(num2str(app.HSlider.Value), ',', num2str(app.SSlider.Value), ',', num2str(app.VSlider.Value));
            app.HigherEditField.Value = strcat(num2str(app.HSlider_2.Value), ',', num2str(app.SSlider_2.Value), ',', num2str(app.VSlider_2.Value));
            
            %[modifiedIm, mask] = applyThreshold(hsvimg, [200/255,35/255,0/255], [1,1,200/255]); % purple mask
            %[modifiedIm, mask] = applyThreshold(app.resizedhsvimg, [value/255,50/255,100/255], [1,120/255,200/255]); % black mask
            %[modifiedIm2, mask2] = applyThreshold(Im, [0,0,0], [60,60,60]); % black mask
            
            %imwrite(applyMask(Im, mask, 1.5),'test.tif','Compression','none','Resolution',100);
            %imwrite(hsvimg,'hsvimg.tif','Compression','none','Resolution',100);
            %mask = mask1 | mask2;
            %mask = bwareaopen(mask, 10); % ruis verwijderen
            %figure
            %imshow(mask);
            %se = strel('disk',1);
            %mask = imclose(mask,se);
            %figure
            %imshow(mask);
            
            %newIm = applyMask(app.resizedhsvimg, mask, 1.5);


            %figure
            %imshow(newIm);
            %imshow(hsv2rgb(modifiedIm));
            %imwrite(newIm,'test.tif','Compression','none','Resolution',100);
            %% Filteren mask
            %newMask = filtermask(app.resizedhsvimg, mask);
            %newMask = mask;
            %newMask = bwareaopen(newMask, 10); % ruis verwijderen
            %newMask = mask;
            %imshow(newMask);
            %se = strel('disk',1);
            
            %se = strel('diamond',1);
            %newMask = imclose(newMask,se);
            
            %imshow(applyMask(app.resizedImage, newMask, 2), 'Parent', app.UIAxes_2);
            %imwrite(applyMask(Im, newMask, 2),'test2.tif','Compression','none','Resolution',100);
        end

        % Button pushed function: MapkiezenButton
        function MapkiezenButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            images = mapInlezen();
            if ~isempty(images)
                for i = 1:length(images)                    
                    Im = imread(images(i));
                    imshow(imresize(Im, 0.25), 'Parent', app.UIAxes_4)
                    %figure
                    gray = rgb2gray(Im);
                    %imshow(gray)
                    mask = gray > 200;
                    gray(mask) = 0;
                    gray(~mask) = 255;
                    %imshow(gray)
                    fillHoles = imfill(gray,'holes');
                    %imshow(fillHoles)
                    %figure
                    beter = bwareaopen(fillHoles, 1500);
                    %imshow(beter)
                    %figure
                                       
                    
                    r = Im(:,:,1);
                    g = Im(:,:,2);
                    b = Im(:,:,3);
                    r(beter==0) = 255;
                    g(beter==0) = 255;
                    b(beter==0) = 255;
                    Im(:,:,1) = r;
                    Im(:,:,2) = g;
                    Im(:,:,3) = b;
                    
                    %imshow(Im)
                    %figure
                    
                    [height, width] = size(beter);
                    buffer = 300;
                                        
                    
                    [rows, columns] = find(beter);
                    row1 = max([min(rows) - buffer, 1]);
                    row2 = min([max(rows) + buffer, height]);
                    col1 = max([min(columns) - buffer, 1]);
                    col2 = min([max(columns) + buffer, width]);


                    app.croppedImage = Im(row1:row2, col1:col2,:);
                    imshow(app.croppedImage, 'Parent', app.UIAxes_3);
                    
                    app.resizedImage = imresize(app.croppedImage,0.3);
                    app.resizedcolorspaceimg = rgb2hsv(app.resizedImage);
                    
                    app.HSlider.Value = app.tresLow(1)*255;
                    app.HSlider_2.Value = app.tresHigh(1)*255;
                    app.SSlider.Value = app.tresLow(2)*255;
                    app.SSlider_2.Value = app.tresHigh(2)*255;
                    app.VSlider.Value = app.tresLow(3)*255;
                    app.VSlider_2.Value = app.tresHigh(3)*255;


                    saveas(croppedImage,'foto')
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FotokiezenButton = uibutton(app.UIFigure, 'push');
            app.FotokiezenButton.ButtonPushedFcn = createCallbackFcn(app, @FotokiezenButtonPushed, true);
            app.FotokiezenButton.Text = 'Foto kiezen';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CroppedTab = uitab(app.TabGroup);
            app.CroppedTab.Title = 'Cropped';
            app.UIAxes_3 = uiaxes(app.CroppedTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.PaarsTab = uitab(app.TabGroup);
            app.PaarsTab.Title = 'Paars';
            app.UIAxes_2 = uiaxes(app.PaarsTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GeelTab = uitab(app.TabGroup);
            app.GeelTab.Title = 'Geel';
            app.UIAxes = uiaxes(app.GeelTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.PercentageGeelEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageGeelEditFieldLabel.Text = 'Percentage Geel';
            app.PercentageGeelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageGeelEditField.Editable = 'off';
            app.PercentagePaarsEditFieldLabel = uilabel(app.UIFigure);
            app.PercentagePaarsEditFieldLabel.Text = 'Percentage Paars';
            app.PercentagePaarsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentagePaarsEditField.Editable = 'off';
            app.OpslaanalsDropDownLabel = uilabel(app.UIFigure);
            app.OpslaanalsDropDownLabel.Text = 'Opslaan als';
            app.OpslaanalsDropDown = uidropdown(app.UIFigure);
            app.OpslaanalsDropDown.Items = {'Hoge resolutie', 'Lage resolutie', 'Option 3', 'Option 4'};
            app.OpslaanalsDropDown.Value = 'Hoge resolutie';
            app.OpslaanButton = uibutton(app.UIFigure, 'push');
            app.OpslaanButton.ButtonPushedFcn = createCallbackFcn(app, @OpslaanButtonPushed, true);
            app.OpslaanButton.Text = 'Opslaan';
            app.HSliderLabel = uilabel(app.UIFigure);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.UIFigure);
            app.HSlider.Limits = [0 255];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider_2Label = uilabel(app.UIFigure);
            app.HSlider_2Label.Text = 'H';
            app.HSlider_2 = uislider(app.UIFigure);
            app.HSlider_2.Limits = [0 255];
            app.HSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.ColorspaceDropDownLabel = uilabel(app.UIFigure);
            app.ColorspaceDropDownLabel.Text = 'Color space';
            app.ColorspaceDropDown = uidropdown(app.UIFigure);
            app.ColorspaceDropDown.Items = {'HSV', 'RGB', 'CMYK', ''};
            app.ColorspaceDropDown.Value = 'HSV';
            app.SSlider_2Label = uilabel(app.UIFigure);
            app.SSlider_2Label.Text = 'S';
            app.SSlider_2 = uislider(app.UIFigure);
            app.SSlider_2.Limits = [0 255];
            app.SSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.SSliderLabel = uilabel(app.UIFigure);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.UIFigure);
            app.SSlider.Limits = [0 255];
            app.SSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSlider_2Label = uilabel(app.UIFigure);
            app.VSlider_2Label.Text = 'V';
            app.VSlider_2 = uislider(app.UIFigure);
            app.VSlider_2.Limits = [0 255];
            app.VSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [0 255];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.LowerEditFieldLabel = uilabel(app.UIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.UIFigure, 'text');
            app.HigherEditFieldLabel = uilabel(app.UIFigure);
            app.HigherEditFieldLabel.Text = 'Higher';
            app.HigherEditField = uieditfield(app.UIFigure, 'text');
            app.MapkiezenButton = uibutton(app.UIFigure, 'push');
            app.MapkiezenButton.ButtonPushedFcn = createCallbackFcn(app, @MapkiezenButtonPushed, true);
            app.MapkiezenButton.Text = 'Map kiezen';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototypeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end