classdef prototypeAppRobin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FotokiezenButton               matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        CroppedTab                     matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        IndicatorTab                   matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        Tab_3                          matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        Tab                            matlab.ui.container.Tab
        UIAxes_5                       matlab.ui.control.UIAxes
        Tab_4                          matlab.ui.container.Tab
        UIAxes_6                       matlab.ui.control.UIAxes
        Tab_5                          matlab.ui.container.Tab
        UIAxes_7                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        PercentageGeelEditFieldLabel   matlab.ui.control.Label
        PercentageGeelEditField        matlab.ui.control.NumericEditField
        PercentagePaarsEditFieldLabel  matlab.ui.control.Label
        PercentagePaarsEditField       matlab.ui.control.NumericEditField
        OpslaanalsDropDownLabel        matlab.ui.control.Label
        OpslaanalsDropDown             matlab.ui.control.DropDown
        OpslaanButton                  matlab.ui.control.Button
        HSliderLabel                   matlab.ui.control.Label
        HSlider                        matlab.ui.control.Slider
        HSlider_2Label                 matlab.ui.control.Label
        HSlider_2                      matlab.ui.control.Slider
        ColorspaceDropDownLabel        matlab.ui.control.Label
        ColorspaceDropDown             matlab.ui.control.DropDown
        SSlider_2Label                 matlab.ui.control.Label
        SSlider_2                      matlab.ui.control.Slider
        SSliderLabel                   matlab.ui.control.Label
        SSlider                        matlab.ui.control.Slider
        VSlider_2Label                 matlab.ui.control.Label
        VSlider_2                      matlab.ui.control.Slider
        VSliderLabel                   matlab.ui.control.Label
        VSlider                        matlab.ui.control.Slider
        LowerEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.EditField
        HigherEditFieldLabel           matlab.ui.control.Label
        HigherEditField                matlab.ui.control.EditField
        BezigmetomzettenkleurwaardenLabel  matlab.ui.control.Label
        KSlider_2Label                 matlab.ui.control.Label
        KSlider_2                      matlab.ui.control.Slider
        KSliderLabel                   matlab.ui.control.Label
        KSlider                        matlab.ui.control.Slider
    end

    
    properties (Access = private)
        croppedImage
        resizedcolorspaceimg
        resizedImage
        tresLow = [200/255,50/255,100/255];
        tresHigh = [1,120/255,200/255];
        tresRGBLow = [0,30.6,88.4];
        tresRGBHigh = [255,154.7,134.3];
        divide = 1;
        mask;
    end


    methods (Access = private)
        
        
        function [] = laadKleurwaarde(app, naam)
            m = min(2000/max(size(app.croppedImage)), 1); % niet vergrote afbeelding
            app.resizedImage = imresize(app.croppedImage,m);
                
            app.BezigmetomzettenkleurwaardenLabel.Visible = true;
            app.divide = 1;
            if strcmp(naam, 'HSV') == 1
                app.resizedcolorspaceimg = rgb2hsv(app.resizedImage);
                app.tresLow = [200/255,50/255,100/255];
                app.tresHigh = [1,120/255,200/255];
                app.divide = 255;
            elseif strcmp(naam, 'RGB') == 1
                app.resizedcolorspaceimg = app.resizedImage;
                app.tresLow = app.tresRGBLow;
                app.tresHigh = app.tresRGBHigh;
            elseif strcmp(naam, 'CMYK') == 1
                app.resizedcolorspaceimg = rgb2cmyk(app.resizedImage);
                app.tresLow = [0,66.3,0,0];
                app.tresHigh = [1,146.2,1,0];
                app.divide = 1;
            end
            app.BezigmetomzettenkleurwaardenLabel.Visible = false;
            
            app.HSlider.Value = app.round(app.tresLow(1)*app.divide);
            app.HSlider_2.Value = app.round(app.tresHigh(1)*app.divide);
            app.HSliderLabel.Text = naam(1);
            app.HSlider_2Label.Text = naam(1);
            app.SSlider.Value = app.round(app.tresLow(2)*app.divide);
            app.SSlider_2.Value = app.round(app.tresHigh(2)*app.divide);
            app.SSliderLabel.Text = naam(2);
            app.SSlider_2Label.Text = naam(2);
            app.VSlider.Value = app.round(app.tresLow(3)*app.divide);
            app.VSlider_2.Value = app.round(app.tresHigh(3)*app.divide);
            app.VSliderLabel.Text = naam(3);
            app.VSlider_2Label.Text = naam(3);
            
            v = size(app.resizedcolorspaceimg)
            %for i = 1:v(3)
            %imshow(app.resizedcolorspaceimg(:,:,1), 'Parent', app.UIAxes);
            app.Tab_3.Title = naam(1);
            %imshow(app.resizedcolorspaceimg(:,:,2), 'Parent', app.UIAxes_5);
            app.Tab.Title = naam(2);
            %imshow(app.resizedcolorspaceimg(:,:,3), 'Parent', app.UIAxes_6);
            app.Tab_4.Title = naam(3);
            %end
            if v(3) > 3
                app.KSlider.Value = app.round(app.tresLow(4)*app.divide);
                app.KSlider_2.Value = app.round(app.tresHigh(4)*app.divide);
                app.KSliderLabel.Text = naam(4);
                app.KSlider_2Label.Text = naam(4);
                app.KSlider.Visible = true;
                app.KSlider_2.Visible = true;
                
                app.LowerEditField.Value = strcat(num2str(app.HSlider.Value), ',', num2str(app.SSlider.Value), ',', num2str(app.VSlider.Value), ',', num2str(app.KSlider.Value));
                app.HigherEditField.Value = strcat(num2str(app.HSlider_2.Value), ',', num2str(app.SSlider_2.Value), ',', num2str(app.VSlider_2.Value), ',', num2str(app.KSlider.Value));
            
                
                %imshow(app.resizedcolorspaceimg(:,:,4), 'Parent', app.UIAxes_7);
                app.Tab_5.Title = naam(4);
                
                [modifiedIm, mask] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide,app.KSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide, app.KSlider_2.Value/app.divide], true);
                imshow(app.showMask(4, mask), 'Parent', app.UIAxes_7);
            else
                app.KSlider.Visible = false;
                app.KSlider_2.Visible = false;
                app.KSliderLabel.Text = '';
                app.KSlider_2Label.Text = '';
                
                app.LowerEditField.Value = strcat(num2str(app.HSlider.Value), ',', num2str(app.SSlider.Value), ',', num2str(app.VSlider.Value));
                app.HigherEditField.Value = strcat(num2str(app.HSlider_2.Value), ',', num2str(app.SSlider_2.Value), ',', num2str(app.VSlider_2.Value));
            
                
                app.Tab_5.Title = '';
                
                [modifiedIm, mask] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide], true);
            end
            
            % inladen bewerkte foto
            app.mask = mask;
            imshow(app.showMask(1, mask), 'Parent', app.UIAxes);
            imshow(app.showMask(2, mask), 'Parent', app.UIAxes_5);
            imshow(app.showMask(3, mask), 'Parent', app.UIAxes_6);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
        end
        
        function rounded = round(app, value)
            rounded = double(min(255, max(0, value)))
        end
        
        function [im] = showMask(app, index, mask)
            c = app.resizedcolorspaceimg(:,:,index);
            
            %im = cMask;
            applied = c;
            applied(mask) = applied(mask) * 2;
            applied(applied > 255) = 255;
            c(mask) = c(mask) * 0.5;
            
            im = cat(3, c, c, applied);
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: FotokiezenButton
        function FotokiezenButtonPushed(app, event)
            [file,path]=uigetfile({'*.*',  'All Files (*.*)'}, 'Select file');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if file ~= 0
                progressBar = waitbar(0, 'Loading your blood clot');
                Im = imread(file);
                imshow(imresize(Im, 0.25), 'Parent', app.UIAxes_4)
                %figure
                gray = rgb2gray(Im);
                %imshow(gray)
                mask = gray > 200;
                gray(mask) = 0;
                gray(~mask) = 255;
                %imshow(gray)
                fillHoles = imfill(gray,'holes');
                %imshow(fillHoles)
                %figure
                beter = bwareaopen(fillHoles, 1500);
                %imshow(beter)
                %figure
                
                waitbar(0.25, progressBar, 'Loading your blood clot')
                
                r = Im(:,:,1);
                g = Im(:,:,2);
                b = Im(:,:,3);
                r(beter==0) = 255;
                g(beter==0) = 255;
                b(beter==0) = 255;
                Im(:,:,1) = r;
                Im(:,:,2) = g;
                Im(:,:,3) = b;
                
                %imshow(Im)
                %figure
                
                [height, width] = size(beter);
                buffer = 300;
                
                waitbar(0.66, progressBar, 'Loading your blood clot')
                
                [rows, columns] = find(beter);
                row1 = max([min(rows) - buffer, 1]);
                row2 = min([max(rows) + buffer, height]);
                col1 = max([min(columns) - buffer, 1]);
                col2 = min([max(columns) + buffer, width]);
                
                waitbar(1, progressBar, 'Loading your blood clot')
                close(progressBar)
                
                app.croppedImage = Im(row1:row2, col1:col2,:);
                imshow(app.croppedImage, 'Parent', app.UIAxes_3);
                
                app.laadKleurwaarde(app.ColorspaceDropDown.Value);
                
             end
        end

        % Button pushed function: OpslaanButton
        function OpslaanButtonPushed(app, event)

            if app.OpslaanalsDropDown.Value == 'Hoge resolutie'
                imwrite(app.croppedImage, 'testOpslaanButtonHoog.jpg', 'Quality', 100)
            else
                imwrite(app.croppedImage, 'testOpslaanButtonLaag.jpg', 'Quality', 5)
            end
        end

        % Value changed function: HSlider, HSlider_2, SSlider, 
        % SSlider_2, VSlider, VSlider_2
        function HSliderValueChanged(app, event)

            %figure
            %imshow(hsvimg);
            v = size(app.resizedcolorspaceimg);
            
            %[v1, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, );
            %[v2, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.SSlider.Value/app.divide], [app.SSlider_2.Value/app.divide]);
            %imshow(v2, 'Parent', app.UIAxes_5);
            %[v3, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.VSlider.Value/app.divide], [app.VSlider_2.Value/app.divide]);
            %imshow(v3, 'Parent', app.UIAxes_6);
            if v(3) > 3
                %[v4, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.KSlider.Value/app.divide], [app.KSlider_2.Value/app.divide]);
                %imshow(v4, 'Parent', app.UIAxes_7);
                
                app.LowerEditField.Value = strcat(num2str(app.HSlider.Value), ',', num2str(app.SSlider.Value), ',', num2str(app.VSlider.Value), ',', num2str(app.KSlider.Value));
                app.HigherEditField.Value = strcat(num2str(app.HSlider_2.Value), ',', num2str(app.SSlider_2.Value), ',', num2str(app.VSlider_2.Value), ',', num2str(app.KSlider.Value));
            
                [modifiedIm, mask] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide, app.KSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide, app.KSlider_2.Value/app.divide], false);
                imshow(app.showMask(4, mask), 'Parent', app.UIAxes_7);
            else
                app.LowerEditField.Value = strcat(num2str(app.HSlider.Value), ',', num2str(app.SSlider.Value), ',', num2str(app.VSlider.Value));
                app.HigherEditField.Value = strcat(num2str(app.HSlider_2.Value), ',', num2str(app.SSlider_2.Value), ',', num2str(app.VSlider_2.Value));
            
                [modifiedIm, mask] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/app.divide,app.SSlider.Value/app.divide,app.VSlider.Value/app.divide], [app.HSlider_2.Value/app.divide,app.SSlider_2.Value/app.divide,app.VSlider_2.Value/app.divide], false);
            end
            %[modifiedIm, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/divide,app.SSlider.Value/divide,app.VSlider.Value/divide], [app.HSlider_2.Value/divide,app.SSlider_2.Value/divide,app.VSlider_2.Value/divide]);
            app.mask = mask;
            imshow(app.showMask(1, mask), 'Parent', app.UIAxes);
            imshow(app.showMask(2, mask), 'Parent', app.UIAxes_5);
            imshow(app.showMask(3, mask), 'Parent', app.UIAxes_6);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
            
            
            %[modifiedIm, mask] = applyThreshold(hsvimg, [200/255,35/255,0/255], [1,1,200/255]); % purple mask
            %[modifiedIm, mask] = applyThreshold(app.resizedhsvimg, [value/255,50/255,100/255], [1,120/255,200/255]); % black mask
            %[modifiedIm2, mask2] = applyThreshold(Im, [0,0,0], [60,60,60]); % black mask
            
            %imwrite(applyMask(Im, mask, 1.5),'test.tif','Compression','none','Resolution',100);
            %imwrite(hsvimg,'hsvimg.tif','Compression','none','Resolution',100);
            %mask = mask1 | mask2;
            %mask = bwareaopen(mask, 10); % ruis verwijderen
            %figure
            %imshow(mask);
            %se = strel('disk',1);
            %mask = imclose(mask,se);
            %figure
            %imshow(mask);
            
            %newIm = applyMask(app.resizedhsvimg, mask, 1.5);


            %figure
            %imshow(newIm);
            %imshow(hsv2rgb(modifiedIm));
            %imwrite(newIm,'test.tif','Compression','none','Resolution',100);
            %% Filteren mask
            %newMask = filtermask(app.resizedhsvimg, mask);
            %newMask = mask;
            %newMask = bwareaopen(newMask, 10); % ruis verwijderen
            %newMask = mask;
            %imshow(newMask);
            %se = strel('disk',1);
            
            %se = strel('diamond',1);
            %newMask = imclose(newMask,se);
            
            %imshow(applyMask(app.resizedImage, newMask, 2), 'Parent', app.UIAxes_2);
            %imwrite(applyMask(Im, newMask, 2),'test2.tif','Compression','none','Resolution',100);
        end

        % Value changed function: ColorspaceDropDown
        function ColorspaceDropDownValueChanged(app, event)
            value = app.ColorspaceDropDown.Value;
            app.laadKleurwaarde(value)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FotokiezenButton = uibutton(app.UIFigure, 'push');
            app.FotokiezenButton.ButtonPushedFcn = createCallbackFcn(app, @FotokiezenButtonPushed, true);
            app.FotokiezenButton.Text = 'Foto kiezen';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CroppedTab = uitab(app.TabGroup);
            app.CroppedTab.Title = 'Cropped';
            app.UIAxes_3 = uiaxes(app.CroppedTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.IndicatorTab = uitab(app.TabGroup);
            app.IndicatorTab.Title = 'Indicator';
            app.UIAxes_2 = uiaxes(app.IndicatorTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab_3);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_5 = uiaxes(app.Tab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Tab';
            app.UIAxes_6 = uiaxes(app.Tab_4);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = 'Tab';
            app.UIAxes_7 = uiaxes(app.Tab_5);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.PercentageGeelEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageGeelEditFieldLabel.Text = 'Percentage Geel';
            app.PercentageGeelEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageGeelEditField.Editable = 'off';
            app.PercentagePaarsEditFieldLabel = uilabel(app.UIFigure);
            app.PercentagePaarsEditFieldLabel.Text = 'Percentage Paars';
            app.PercentagePaarsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentagePaarsEditField.Editable = 'off';
            app.OpslaanalsDropDownLabel = uilabel(app.UIFigure);
            app.OpslaanalsDropDownLabel.Text = 'Opslaan als';
            app.OpslaanalsDropDown = uidropdown(app.UIFigure);
            app.OpslaanalsDropDown.Items = {'Hoge resolutie', 'Lage resolutie', 'Option 3', 'Option 4'};
            app.OpslaanalsDropDown.Value = 'Hoge resolutie';
            app.OpslaanButton = uibutton(app.UIFigure, 'push');
            app.OpslaanButton.ButtonPushedFcn = createCallbackFcn(app, @OpslaanButtonPushed, true);
            app.OpslaanButton.Text = 'Opslaan';
            app.HSliderLabel = uilabel(app.UIFigure);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.UIFigure);
            app.HSlider.Limits = [0 255];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider_2Label = uilabel(app.UIFigure);
            app.HSlider_2Label.Text = 'H';
            app.HSlider_2 = uislider(app.UIFigure);
            app.HSlider_2.Limits = [0 255];
            app.HSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.ColorspaceDropDownLabel = uilabel(app.UIFigure);
            app.ColorspaceDropDownLabel.Text = 'Color space';
            app.ColorspaceDropDown = uidropdown(app.UIFigure);
            app.ColorspaceDropDown.Items = {'RGB', 'HSV', 'CMYK', ''};
            app.ColorspaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorspaceDropDownValueChanged, true);
            app.ColorspaceDropDown.Value = 'RGB';
            app.SSlider_2Label = uilabel(app.UIFigure);
            app.SSlider_2Label.Text = 'S';
            app.SSlider_2 = uislider(app.UIFigure);
            app.SSlider_2.Limits = [0 255];
            app.SSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.SSliderLabel = uilabel(app.UIFigure);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.UIFigure);
            app.SSlider.Limits = [0 255];
            app.SSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSlider_2Label = uilabel(app.UIFigure);
            app.VSlider_2Label.Text = 'V';
            app.VSlider_2 = uislider(app.UIFigure);
            app.VSlider_2.Limits = [0 255];
            app.VSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [0 255];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.LowerEditFieldLabel = uilabel(app.UIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.UIFigure, 'text');
            app.HigherEditFieldLabel = uilabel(app.UIFigure);
            app.HigherEditFieldLabel.Text = 'Higher';
            app.HigherEditField = uieditfield(app.UIFigure, 'text');
            app.BezigmetomzettenkleurwaardenLabel = uilabel(app.UIFigure);
            app.BezigmetomzettenkleurwaardenLabel.Visible = 'off';
            app.BezigmetomzettenkleurwaardenLabel.Text = 'Bezig met omzetten kleurwaarden...';
            app.KSlider_2Label = uilabel(app.UIFigure);
            app.KSlider_2Label.Text = 'K';
            app.KSlider_2 = uislider(app.UIFigure);
            app.KSlider_2.Limits = [0 255];
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [0 255];
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototypeAppRobin

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end