classdef prototypeAppToon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DestinationFoldersMenu          matlab.ui.container.Menu
        RawDataMenu                     matlab.ui.container.Menu
        EditedDataMenu                  matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        SelectasinglefileButton         matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CroppedTab                      matlab.ui.container.Tab
        UIAxes_3                        matlab.ui.control.UIAxes
        PaarsTab                        matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        GeelTab                         matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        PercentageEditFieldLabel        matlab.ui.control.Label
        PercentageEditField             matlab.ui.control.NumericEditField
        SaveButton                      matlab.ui.control.Button
        HSliderLabel                    matlab.ui.control.Label
        HSlider                         matlab.ui.control.Slider
        HSlider_2Label                  matlab.ui.control.Label
        HSlider_2                       matlab.ui.control.Slider
        ColorspaceDropDownLabel         matlab.ui.control.Label
        ColorspaceDropDown              matlab.ui.control.DropDown
        SSlider_2Label                  matlab.ui.control.Label
        SSlider_2                       matlab.ui.control.Slider
        SSliderLabel                    matlab.ui.control.Label
        SSlider                         matlab.ui.control.Slider
        VSlider_2Label                  matlab.ui.control.Label
        VSlider_2                       matlab.ui.control.Slider
        VSliderLabel                    matlab.ui.control.Label
        VSlider                         matlab.ui.control.Slider
        LowerEditFieldLabel             matlab.ui.control.Label
        LowerEditField                  matlab.ui.control.EditField
        HigherEditFieldLabel            matlab.ui.control.Label
        HigherEditField                 matlab.ui.control.EditField
        BackgroundThresholdSliderLabel  matlab.ui.control.Label
        BackgroundThresholdSlider       matlab.ui.control.Slider
        SelectanentirefolderButton      matlab.ui.control.Button
    end

    properties (Access = private)
        rawImage
        croppedImage
        resizedcolorspaceimg
        resizedImage
        tresLow = [200/255,50/255,100/255,0]
        tresHigh = [1,120/255,200/255,0]
        rawDataPath
        editedDataPath
        file
    end     

    methods (Access = private)

        % Button pushed function: SelectasinglefileButton
        function SelectasinglefileButtonPushed(app, event)
            app.file=uigetfile({'*.*',  'All Files (*.*)'}, 'Select file', app.rawDataPath);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if app.file ~= 0
                
                progressBar = waitbar(0, 'Loading your blood clot');
                
                %MASK BEPALEN
                app.rawImage = imread(strcat(app.rawDataPath, app.file));

                gray = rgb2gray(app.rawImage);
                bestThreshold = getBestThreshold(gray, 200);
                mask = gray > bestThreshold;
                gray(mask) = 0;
                gray(~mask) = 255;
                se = strel('disk',4);
                fillHoles = imclose(gray,se);
                fillHoles = ~bwareaopen(~gray, 1500);
                finalMask = bwareaopen(fillHoles, 5000);

                waitbar(0.25, progressBar, 'Loading your blood clot')
                
                %MASK TOEPASSEN
                app.croppedImage = app.rawImage;
                r = app.croppedImage(:,:,1);
                g = app.croppedImage(:,:,2);
                b = app.croppedImage(:,:,3);
                r(finalMask==0) = 255;
                g(finalMask==0) = 255;
                b(finalMask==0) = 255;
                app.croppedImage(:,:,1) = r;
                app.croppedImage(:,:,2) = g;
                app.croppedImage(:,:,3) = b;

                waitbar(0.66, progressBar, 'Loading your blood clot')
                
                %AFBEELDING BIJSNIJDEN
                [height, width] = size(finalMask);
                buffer = 300;
         
                [rows, columns] = find(finalMask);
                row1 = max([min(rows) - buffer, 1]);
                row2 = min([max(rows) + buffer, height]);
                col1 = max([min(columns) - buffer, 1]);
                col2 = min([max(columns) + buffer, width]);
                              
                app.rawImage = app.rawImage(row1:row2, col1:col2,:);
                app.croppedImage = app.croppedImage(row1:row2, col1:col2,:);
                app.croppedImage(end-80:end,1:330,:) = 255;
                
                %AFBEELDINGEN LADEN
                imshow(app.croppedImage, 'Parent', app.UIAxes_3);
                imshow(imresize(app.rawImage, 0.25), 'Parent', app.UIAxes_4)
                
                app.resizedImage = imresize(app.croppedImage,0.3);
                app.resizedcolorspaceimg = rgb2hsv(app.resizedImage);
                
                waitbar(1, progressBar, 'Loading your blood clot')
                close(progressBar)
                            
                %BIJGESNEDEN FOTO OPSLAAN
                imwrite(app.croppedImage, strcat(app.editedDataPath, app.file, 'CLEAN.jpg'))
                
                %WAARDEN OVERSCHRIJVEN NAAR SLIDERS
                app.HSlider.Value = app.tresLow(1)*255;
                app.HSlider_2.Value = app.tresHigh(1)*255;
                app.SSlider.Value = app.tresLow(2)*255;
                app.SSlider_2.Value = app.tresHigh(2)*255;
                app.VSlider.Value = app.tresLow(3)*255;
                app.VSlider_2.Value = app.tresHigh(3)*255;
                app.BackgroundThresholdSlider.Value = bestThreshold;
                              
                % inladen bewerkte foto
                divide = 1;
                if app.ColorspaceDropDown.Value == 'HSV'
                    divide = 255;
                end
                [modifiedIm, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/divide,app.SSlider.Value/divide,app.VSlider.Value/divide], [app.HSlider_2.Value/divide,app.SSlider_2.Value/divide,app.VSlider_2.Value/divide]);
                imshow(modifiedIm, 'Parent', app.UIAxes_2);
                
             end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                imwrite(app.croppedImage, strcat(app.editedDataPath, app.file, 'CLEAN.jpg'))
        end

        % Value changed function: HSlider, HSlider_2, SSlider, 
        % SSlider_2, VSlider, VSlider_2
        function HSliderValueChanged(app, event)

            %figure
            %imshow(hsvimg);
            divide = 1;
            if app.ColorspaceDropDown.Value == 'HSV'
                divide = 255;
            end
            [modifiedIm, ~] = bewerkAfbeelding(app.resizedcolorspaceimg, app.resizedImage, [app.HSlider.Value/divide,app.SSlider.Value/divide,app.VSlider.Value/divide], [app.HSlider_2.Value/divide,app.SSlider_2.Value/divide,app.VSlider_2.Value/divide]);
            imshow(modifiedIm, 'Parent', app.UIAxes_2);
            
            app.LowerEditField.Value = strcat(num2str(app.HSlider.Value), ',', num2str(app.SSlider.Value), ',', num2str(app.VSlider.Value));
            app.HigherEditField.Value = strcat(num2str(app.HSlider_2.Value), ',', num2str(app.SSlider_2.Value), ',', num2str(app.VSlider_2.Value));
            
            %[modifiedIm, mask] = applyThreshold(hsvimg, [200/255,35/255,0/255], [1,1,200/255]); % purple mask
            %[modifiedIm, mask] = applyThreshold(app.resizedhsvimg, [value/255,50/255,100/255], [1,120/255,200/255]); % black mask
            %[modifiedIm2, mask2] = applyThreshold(Im, [0,0,0], [60,60,60]); % black mask
            
            %imwrite(applyMask(Im, mask, 1.5),'test.tif','Compression','none','Resolution',100);
            %imwrite(hsvimg,'hsvimg.tif','Compression','none','Resolution',100);
            %mask = mask1 | mask2;
            %mask = bwareaopen(mask, 10); % ruis verwijderen
            %figure
            %imshow(mask);
            %se = strel('disk',1);
            %mask = imclose(mask,se);
            %figure
            %imshow(mask);
            
            %newIm = applyMask(app.resizedhsvimg, mask, 1.5);


            %figure
            %imshow(newIm);
            %imshow(hsv2rgb(modifiedIm));
            %imwrite(newIm,'test.tif','Compression','none','Resolution',100);
            %% Filteren mask
            %newMask = filtermask(app.resizedhsvimg, mask);
            %newMask = mask;
            %newMask = bwareaopen(newMask, 10); % ruis verwijderen
            %newMask = mask;
            %imshow(newMask);
            %se = strel('disk',1);
            
            %se = strel('diamond',1);
            %newMask = imclose(newMask,se);
            
            %imshow(applyMask(app.resizedImage, newMask, 2), 'Parent', app.UIAxes_2);
            %imwrite(applyMask(Im, newMask, 2),'test2.tif','Compression','none','Resolution',100);
        end

        % Value changed function: BackgroundThresholdSlider
        function BackgroundThresholdSliderValueChanged(app, event)
            value = app.BackgroundThresholdSlider.Value;
            
            %MASK BEPALEN
            gray = rgb2gray(app.rawImage);
            mask = gray > value;
            gray(mask) = 0;
            gray(~mask) = 255;
            se = strel('disk',4);
            fillHoles = imclose(gray,se);
            fillHoles = ~bwareaopen(~gray, 1500);
            finalMask = bwareaopen(fillHoles, 5000);
            
            %MASK TOEPASSEN
            app.croppedImage = app.rawImage;
            r = app.croppedImage(:,:,1);
            g = app.croppedImage(:,:,2);
            b = app.croppedImage(:,:,3);
            r(finalMask==0) = 255;
            g(finalMask==0) = 255;
            b(finalMask==0) = 255;
            app.croppedImage(:,:,1) = r;
            app.croppedImage(:,:,2) = g;
            app.croppedImage(:,:,3) = b;
            app.croppedImage(end-80:end,1:330,:) = 255;
                       
            %AFBEELDINGEN LADEN
            imshow(app.croppedImage, 'Parent', app.UIAxes_3);                                     
        end

        % Menu selected function: RawDataMenu
        function RawDataMenuSelected(app, event)
            app.rawDataPath = strcat(uigetdir, '\'); 
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Menu selected function: EditedDataMenu
        function EditedDataMenuSelected(app, event)
            app.editedDataPath = strcat(uigetdir, '\');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DestinationFoldersMenu = uimenu(app.UIFigure);
            app.DestinationFoldersMenu.Text = 'Destination Folders';
            app.RawDataMenu = uimenu(app.DestinationFoldersMenu);
            app.RawDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDataMenuSelected, true);
            app.RawDataMenu.Text = 'Raw Data';
            app.EditedDataMenu = uimenu(app.DestinationFoldersMenu);
            app.EditedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @EditedDataMenuSelected, true);
            app.EditedDataMenu.Text = 'Edited Data';
            app.Menu_2 = uimenu(app.UIFigure);
            app.SelectasinglefileButton = uibutton(app.UIFigure, 'push');
            app.SelectasinglefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectasinglefileButtonPushed, true);
            app.SelectasinglefileButton.Text = 'Select a single file';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CroppedTab = uitab(app.TabGroup);
            app.CroppedTab.Title = 'Cropped';
            app.UIAxes_3 = uiaxes(app.CroppedTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.PaarsTab = uitab(app.TabGroup);
            app.PaarsTab.Title = 'Paars';
            app.UIAxes_2 = uiaxes(app.PaarsTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GeelTab = uitab(app.TabGroup);
            app.GeelTab.Title = 'Geel';
            app.UIAxes = uiaxes(app.GeelTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.PercentageEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageEditFieldLabel.Text = 'Percentage';
            app.PercentageEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageEditField.Editable = 'off';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.HSliderLabel = uilabel(app.UIFigure);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.UIFigure);
            app.HSlider.Limits = [0 255];
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider_2Label = uilabel(app.UIFigure);
            app.HSlider_2Label.Text = 'H';
            app.HSlider_2 = uislider(app.UIFigure);
            app.HSlider_2.Limits = [0 255];
            app.HSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.ColorspaceDropDownLabel = uilabel(app.UIFigure);
            app.ColorspaceDropDownLabel.Text = 'Color space';
            app.ColorspaceDropDown = uidropdown(app.UIFigure);
            app.ColorspaceDropDown.Items = {'HSV', 'RGB', 'CMYK', ''};
            app.ColorspaceDropDown.Value = 'HSV';
            app.SSlider_2Label = uilabel(app.UIFigure);
            app.SSlider_2Label.Text = 'S';
            app.SSlider_2 = uislider(app.UIFigure);
            app.SSlider_2.Limits = [0 255];
            app.SSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.SSliderLabel = uilabel(app.UIFigure);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.UIFigure);
            app.SSlider.Limits = [0 255];
            app.SSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSlider_2Label = uilabel(app.UIFigure);
            app.VSlider_2Label.Text = 'V';
            app.VSlider_2 = uislider(app.UIFigure);
            app.VSlider_2.Limits = [0 255];
            app.VSlider_2.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [0 255];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.LowerEditFieldLabel = uilabel(app.UIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.UIFigure, 'text');
            app.HigherEditFieldLabel = uilabel(app.UIFigure);
            app.HigherEditFieldLabel.Text = 'Higher';
            app.HigherEditField = uieditfield(app.UIFigure, 'text');
            app.BackgroundThresholdSliderLabel = uilabel(app.UIFigure);
            app.BackgroundThresholdSliderLabel.Text = 'Background Threshold';
            app.BackgroundThresholdSlider = uislider(app.UIFigure);
            app.BackgroundThresholdSlider.Limits = [180 250];
            app.BackgroundThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BackgroundThresholdSliderValueChanged, true);
            app.BackgroundThresholdSlider.Value = 190;
            app.SelectanentirefolderButton = uibutton(app.UIFigure, 'push');
            app.SelectanentirefolderButton.Text = 'Select an entire folder';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototypeAppToon

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end